classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        JointStatesPanel           matlab.ui.container.Panel
        Joint1SliderLabel          matlab.ui.control.Label
        Joint1Slider               matlab.ui.control.Slider
        Joint2SliderLabel          matlab.ui.control.Label
        Joint2Slider               matlab.ui.control.Slider
        Joint3SliderLabel          matlab.ui.control.Label
        Joint3Slider               matlab.ui.control.Slider
        Joint4SliderLabel          matlab.ui.control.Label
        Joint4Slider               matlab.ui.control.Slider
        Joint5SliderLabel          matlab.ui.control.Label
        Joint5Slider               matlab.ui.control.Slider
        Joint6SliderLabel          matlab.ui.control.Label
        Joint6Slider               matlab.ui.control.Slider
        EStopButton                matlab.ui.control.Button
        StartResetButton           matlab.ui.control.Button
        ResumeButton               matlab.ui.control.Button
        NumericalJointStatesPanel  matlab.ui.container.Panel
        Joint1EditFieldLabel       matlab.ui.control.Label
        Joint1EditField            matlab.ui.control.NumericEditField
        Joint2EditFieldLabel       matlab.ui.control.Label
        Joint2EditField            matlab.ui.control.NumericEditField
        Joint3EditFieldLabel       matlab.ui.control.Label
        Joint3EditField            matlab.ui.control.NumericEditField
        Joint4EditFieldLabel       matlab.ui.control.Label
        Joint4EditField            matlab.ui.control.NumericEditField
        Joint5EditFieldLabel       matlab.ui.control.Label
        Joint5EditField            matlab.ui.control.NumericEditField
        Joint6EditFieldLabel       matlab.ui.control.Label
        Joint6EditField            matlab.ui.control.NumericEditField
        PauseButton                matlab.ui.control.Button
        ManualModeButton           matlab.ui.control.Button
    end

    
    properties (Access = public)
        robot % Description
    end
    
    methods (Access = public)
        function inputrobot(app,robot) % Description
            app.robot=robot;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            app.Joint1EditField.Value= app.Joint1Slider.Value;
            Joint1EditFieldValueChanged(app, event);
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
app.Joint2EditField.Value= app.Joint2Slider.Value;    
Joint2EditFieldValueChanged(app, event);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
app.Joint3EditField.Value= app.Joint3Slider.Value;  
Joint3EditFieldValueChanged(app, event);
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            app.Joint4EditField.Value= app.Joint4Slider.Value;
            Joint4EditFieldValueChanged(app, event);
            
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
app.Joint5EditField.Value= app.Joint5Slider.Value;    
Joint5EditFieldValueChanged(app, event);
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
app.Joint6EditField.Value= app.Joint6Slider.Value;    
Joint6EditFieldValueChanged(app, event);
        end

        % Value changed function: Joint1EditField
        function Joint1EditFieldValueChanged(app, event)
            app.Joint1Slider.Value=app.Joint1EditField.Value;
            q = app.robot.model.getpos;
            q(1)=deg2rad(app.Joint1EditField.Value);      
            app.robot.model.animate(q);
           
        end

        % Value changed function: Joint2EditField
        function Joint2EditFieldValueChanged(app, event)
            app.Joint2Slider.Value=app.Joint2EditField.Value;
            q = app.robot.model.getpos;
            q(2)=deg2rad(app.Joint1EditField.Value);      
            app.robot.model.animate(q);
        end

        % Value changed function: Joint3EditField
        function Joint3EditFieldValueChanged(app, event)
            app.Joint3Slider.Value=app.Joint3EditField.Value;
              q = app.robot.model.getpos;
            q(3)=deg2rad(app.Joint1EditField.Value);      
            app.robot.model.animate(q);

        end

        % Value changed function: Joint4EditField
        function Joint4EditFieldValueChanged(app, event)
            app.Joint4Slider.Value=app.Joint4EditField.Value;
              q = app.robot.model.getpos;
            q(4)=deg2rad(app.Joint1EditField.Value);      
            app.robot.model.animate(q);
            
        end

        % Value changed function: Joint5EditField
        function Joint5EditFieldValueChanged(app, event)
            app.Joint5Slider.Value=app.Joint5EditField.Value;
              q = app.robot.model.getpos;
            q(5)=deg2rad(app.Joint1EditField.Value);      
            app.robot.model.animate(q);
            
        end

        % Value changed function: Joint6EditField
        function Joint6EditFieldValueChanged(app, event)
            app.Joint6Slider.Value=app.Joint6EditField.Value;
              q = app.robot.model.getpos;
            q(6)=deg2rad(app.Joint1EditField.Value);      
            app.robot.model.animate(q);

        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
while true
    uiwait;% Figure once everything works
end
           

        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            uiresume;

        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
           uiwait;
           
        end

        % Button pushed function: StartResetButton
        function StartResetButtonPushed(app, event)
            A2main ;    
        end

        % Button pushed function: ManualModeButton
        function ManualModeButtonPushed(app, event)
manual;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JointStatesPanel = uipanel(app.UIFigure);
            app.JointStatesPanel.Title = 'Joint States';
            app.Joint1SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.JointStatesPanel);
            app.Joint1Slider.Limits = [-360 360];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.JointStatesPanel);
            app.Joint2Slider.Limits = [-90 90];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.JointStatesPanel);
            app.Joint3Slider.Limits = [-170 170];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.JointStatesPanel);
            app.Joint4Slider.Limits = [-360 360];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.JointStatesPanel);
            app.Joint5Slider.Limits = [-180 180];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.JointStatesPanel);
            app.Joint6Slider.Limits = [-360 360];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.StartResetButton = uibutton(app.UIFigure, 'push');
            app.StartResetButton.ButtonPushedFcn = createCallbackFcn(app, @StartResetButtonPushed, true);
            app.StartResetButton.Text = 'Start/Reset';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.NumericalJointStatesPanel = uipanel(app.UIFigure);
            app.NumericalJointStatesPanel.Title = 'Numerical Joint States';
            app.Joint1EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1 ';
            app.Joint1EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint1EditField.ValueChangedFcn = createCallbackFcn(app, @Joint1EditFieldValueChanged, true);
            app.Joint2EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint2EditField.ValueChangedFcn = createCallbackFcn(app, @Joint2EditFieldValueChanged, true);
            app.Joint3EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint3EditField.ValueChangedFcn = createCallbackFcn(app, @Joint3EditFieldValueChanged, true);
            app.Joint4EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint4EditField.ValueChangedFcn = createCallbackFcn(app, @Joint4EditFieldValueChanged, true);
            app.Joint5EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.Joint5EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint5EditField.ValueChangedFcn = createCallbackFcn(app, @Joint5EditFieldValueChanged, true);
            app.Joint6EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint6EditFieldLabel.Text = 'Joint 6';
            app.Joint6EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint6EditField.ValueChangedFcn = createCallbackFcn(app, @Joint6EditFieldValueChanged, true);
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.ManualModeButton = uibutton(app.UIFigure, 'push');
            app.ManualModeButton.ButtonPushedFcn = createCallbackFcn(app, @ManualModeButtonPushed, true);
            app.ManualModeButton.Text = 'Manual Mode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end