classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        JointStatesPanel           matlab.ui.container.Panel
        Joint1SliderLabel          matlab.ui.control.Label
        Joint1Slider               matlab.ui.control.Slider
        Joint2SliderLabel          matlab.ui.control.Label
        Joint2Slider               matlab.ui.control.Slider
        Joint3SliderLabel          matlab.ui.control.Label
        Joint3Slider               matlab.ui.control.Slider
        Joint4SliderLabel          matlab.ui.control.Label
        Joint4Slider               matlab.ui.control.Slider
        Joint5SliderLabel          matlab.ui.control.Label
        Joint5Slider               matlab.ui.control.Slider
        Joint6SliderLabel          matlab.ui.control.Label
        Joint6Slider               matlab.ui.control.Slider
        EStopButton                matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        ResumeButton               matlab.ui.control.Button
        NumericalJointStatesPanel  matlab.ui.container.Panel
        Joint1EditFieldLabel       matlab.ui.control.Label
        Joint1EditField            matlab.ui.control.NumericEditField
        Joint2EditFieldLabel       matlab.ui.control.Label
        Joint2EditField            matlab.ui.control.NumericEditField
        Joint3EditFieldLabel       matlab.ui.control.Label
        Joint3EditField            matlab.ui.control.NumericEditField
        Joint4EditFieldLabel       matlab.ui.control.Label
        Joint4EditField            matlab.ui.control.NumericEditField
        Joint5EditFieldLabel       matlab.ui.control.Label
        Joint5EditField            matlab.ui.control.NumericEditField
        Joint6EditFieldLabel       matlab.ui.control.Label
        Joint6EditField            matlab.ui.control.NumericEditField
        PauseButton                matlab.ui.control.Button
        PlayModeButton             matlab.ui.control.Button
        JogModePanel               matlab.ui.container.Panel
        XButton                    matlab.ui.control.StateButton
        XButton_2                  matlab.ui.control.StateButton
        YButton                    matlab.ui.control.StateButton
        YButton_2                  matlab.ui.control.StateButton
        ZButton                    matlab.ui.control.StateButton
        ZButton_2                  matlab.ui.control.Button
    end

    
    properties (Access = public)
        robot % Description
    end
    
    methods (Access = public)
        function inputrobot(app,robot) % Description
            app.robot=robot;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            app.Joint1EditField.Value= app.Joint1Slider.Value;
            Joint1EditFieldValueChanged(app, event);
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            app.Joint2EditField.Value= app.Joint2Slider.Value;
            Joint2EditFieldValueChanged(app, event);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            app.Joint3EditField.Value= app.Joint3Slider.Value;
            Joint3EditFieldValueChanged(app, event);
        end

        % Value changed function: Joint4Slider
        function Joint4SliderValueChanged(app, event)
            app.Joint4EditField.Value= app.Joint4Slider.Value;
            Joint4EditFieldValueChanged(app, event);
            
        end

        % Value changed function: Joint5Slider
        function Joint5SliderValueChanged(app, event)
            app.Joint5EditField.Value= app.Joint5Slider.Value;
            Joint5EditFieldValueChanged(app, event);
        end

        % Value changed function: Joint6Slider
        function Joint6SliderValueChanged(app, event)
            app.Joint6EditField.Value= app.Joint6Slider.Value;
            Joint6EditFieldValueChanged(app, event);
        end

        % Value changed function: Joint1EditField
        function Joint1EditFieldValueChanged(app, event)
            app.Joint1Slider.Value=app.Joint1EditField.Value;
            q = app.robot.model.getpos;
            q(1)=deg2rad(app.Joint1EditField.Value);
            app.robot.model.animate(q);
            
        end

        % Value changed function: Joint2EditField
        function Joint2EditFieldValueChanged(app, event)
            app.Joint2Slider.Value=app.Joint2EditField.Value;
            q = app.robot.model.getpos;
            q(2)=deg2rad(app.Joint1EditField.Value);
            app.robot.model.animate(q);
        end

        % Value changed function: Joint3EditField
        function Joint3EditFieldValueChanged(app, event)
            app.Joint3Slider.Value=app.Joint3EditField.Value;
            q = app.robot.model.getpos;
            q(3)=deg2rad(app.Joint1EditField.Value);
            app.robot.model.animate(q);
            
        end

        % Value changed function: Joint4EditField
        function Joint4EditFieldValueChanged(app, event)
            app.Joint4Slider.Value=app.Joint4EditField.Value;
            q = app.robot.model.getpos;
            q(4)=deg2rad(app.Joint1EditField.Value);
            app.robot.model.animate(q);
            
        end

        % Value changed function: Joint5EditField
        function Joint5EditFieldValueChanged(app, event)
            app.Joint5Slider.Value=app.Joint5EditField.Value;
            q = app.robot.model.getpos;
            q(5)=deg2rad(app.Joint1EditField.Value);
            app.robot.model.animate(q);
            
        end

        % Value changed function: Joint6EditField
        function Joint6EditFieldValueChanged(app, event)
            app.Joint6Slider.Value=app.Joint6EditField.Value;
            q = app.robot.model.getpos;
            q(6)=deg2rad(app.Joint1EditField.Value);
            app.robot.model.animate(q);
            
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            
            % Figure once everything works
            mfr=msgbox('Emergency Stop Button Pressed. Please close Window and Press Resume.');
            pause(40);
            delete(mfr);
            uiwait;

        end

        % Button pushed function: ResumeButton
        function ResumeButtonPushed(app, event)
            mr=msgbox('Simulation Resumed Have Fun!');
            pause(1);
            delete(mr);
            uiresume;
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
         
            mfp=msgbox('Simulation Paused! Please Press Resume!');
            pause(2);
            delete(mfp);
                        app.JogModePanel.Visible= 'off';
            app.JointStatesPanel.Visible='off';
            app.NumericalJointStatesPanel.Visible='off';
               uiwait;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            app.delete
            mfs=msgbox('Starting Simulation. Please Wait for Environment to Load');
            pause (2);
            delete(mfs);
            close all;
            Main;

        end

        % Button pushed function: PlayModeButton
        function PlayModeButtonPushed(app, event)
            app.delete
            close all
            msf=msgbox('Play Mode Activated! Have fun in the environment! :) ');
            pause(2);
            delete(msf);
            
            manual;
        end

        % Size changed function: JointStatesPanel
        function JointStatesPanelSizeChanged(app, event)
            
        end

        % Size changed function: NumericalJointStatesPanel
        function NumericalJointStatesPanelSizeChanged(app, event)

        end

        % Callback function
        function ResetButtonPushed(app, event)
            mfr=msgbox('Re-setting Estop! Simulation will resume Shortly!');
            pause(2);
            delete(mfr);
uiresume;
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            value = app.XButton.Value;
            j1=app.robot.model.getpos;
            f=app.robot.model.fkine(j1);
            f(1,4)=f(1,4)-0.05;
            q=app.robot.model.ikcon(f,j1);
            app.robot.model.animate(q);
        end

        % Value changed function: XButton_2
        function XButton_2ValueChanged(app, event)
            value = app.XButton_2.Value;
            j1=app.robot.model.getpos;
            f=app.robot.model.fkine(j1);
            f(1,4)=f(1,4)+0.05;
            q=app.robot.model.ikcon(f,j1);
            app.robot.model.animate(q);
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            value = app.YButton.Value;
            j1=app.robot.model.getpos;
            f=app.robot.model.fkine(j1);
            f(2,4)=f(2,4)-0.05;
            q=app.robot.model.ikcon(f,j1);
            app.robot.model.animate(q);
        end

        % Value changed function: YButton_2
        function YButton_2ValueChanged(app, event)
            value = app.YButton_2.Value;
            j1=app.robot.model.getpos;
            f=app.robot.model.fkine(j1);
            f(2,4)=f(2,4)+0.05;
            q=app.robot.model.ikcon(f,j1);
            app.robot.model.animate(q);
        end

        % Value changed function: ZButton
        function ZButtonValueChanged(app, event)
            value = app.ZButton.Value;
            j1=app.robot.model.getpos;
            f=app.robot.model.fkine(j1);
            f(3,4)=f(3,4)-0.05;
            q=app.robot.model.ikcon(f,j1);
            app.robot.model.animate(q);
        end

        % Button pushed function: ZButton_2
        function ZButton_2Pushed(app, event)
            j1=app.robot.model.getpos;
            f=app.robot.model.fkine(j1);
            f(3,4)=f(3,4)+0.05;
            q=app.robot.model.ikcon(f,j1);
            app.robot.model.animate(q);
        end

        % Size changed function: JogModePanel
        function JogModePanelSizeChanged(app, event)
            position = app.JogModePanel.Position;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JointStatesPanel = uipanel(app.UIFigure);
            app.JointStatesPanel.Title = 'Joint States';
            app.JointStatesPanel.SizeChangedFcn = createCallbackFcn(app, @JointStatesPanelSizeChanged, true);
            app.Joint1SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.JointStatesPanel);
            app.Joint1Slider.Limits = [-360 360];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.JointStatesPanel);
            app.Joint2Slider.Limits = [-128.9 128.9];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.JointStatesPanel);
            app.Joint3Slider.Limits = [-147.8 147.8];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint4SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.JointStatesPanel);
            app.Joint4Slider.Limits = [-360 360];
            app.Joint4Slider.ValueChangedFcn = createCallbackFcn(app, @Joint4SliderValueChanged, true);
            app.Joint5SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.JointStatesPanel);
            app.Joint5Slider.Limits = [-120.3 120.3];
            app.Joint5Slider.ValueChangedFcn = createCallbackFcn(app, @Joint5SliderValueChanged, true);
            app.Joint6SliderLabel = uilabel(app.JointStatesPanel);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.JointStatesPanel);
            app.Joint6Slider.Limits = [-360 360];
            app.Joint6Slider.ValueChangedFcn = createCallbackFcn(app, @Joint6SliderValueChanged, true);
            app.EStopButton = uibutton(app.UIFigure, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeButtonPushed, true);
            app.ResumeButton.Text = 'Resume';
            app.NumericalJointStatesPanel = uipanel(app.UIFigure);
            app.NumericalJointStatesPanel.Title = 'Numerical Joint States';
            app.NumericalJointStatesPanel.SizeChangedFcn = createCallbackFcn(app, @NumericalJointStatesPanelSizeChanged, true);
            app.Joint1EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint1EditFieldLabel.Text = 'Joint 1 ';
            app.Joint1EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint1EditField.ValueChangedFcn = createCallbackFcn(app, @Joint1EditFieldValueChanged, true);
            app.Joint2EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint2EditField.ValueChangedFcn = createCallbackFcn(app, @Joint2EditFieldValueChanged, true);
            app.Joint3EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint3EditField.ValueChangedFcn = createCallbackFcn(app, @Joint3EditFieldValueChanged, true);
            app.Joint4EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint4EditField.ValueChangedFcn = createCallbackFcn(app, @Joint4EditFieldValueChanged, true);
            app.Joint5EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.Joint5EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint5EditField.ValueChangedFcn = createCallbackFcn(app, @Joint5EditFieldValueChanged, true);
            app.Joint6EditFieldLabel = uilabel(app.NumericalJointStatesPanel);
            app.Joint6EditFieldLabel.Text = 'Joint 6';
            app.Joint6EditField = uieditfield(app.NumericalJointStatesPanel, 'numeric');
            app.Joint6EditField.ValueChangedFcn = createCallbackFcn(app, @Joint6EditFieldValueChanged, true);
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.PlayModeButton = uibutton(app.UIFigure, 'push');
            app.PlayModeButton.ButtonPushedFcn = createCallbackFcn(app, @PlayModeButtonPushed, true);
            app.PlayModeButton.Text = 'Play Mode';
            app.JogModePanel = uipanel(app.UIFigure);
            app.JogModePanel.Title = 'Jog Mode';
            app.JogModePanel.SizeChangedFcn = createCallbackFcn(app, @JogModePanelSizeChanged, true);
            app.XButton = uibutton(app.JogModePanel, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = '-X';
            app.XButton_2 = uibutton(app.JogModePanel, 'state');
            app.XButton_2.ValueChangedFcn = createCallbackFcn(app, @XButton_2ValueChanged, true);
            app.XButton_2.Text = '+X';
            app.YButton = uibutton(app.JogModePanel, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = '-Y';
            app.YButton_2 = uibutton(app.JogModePanel, 'state');
            app.YButton_2.ValueChangedFcn = createCallbackFcn(app, @YButton_2ValueChanged, true);
            app.YButton_2.Text = '+Y';
            app.ZButton = uibutton(app.JogModePanel, 'state');
            app.ZButton.ValueChangedFcn = createCallbackFcn(app, @ZButtonValueChanged, true);
            app.ZButton.Text = '-Z';
            app.ZButton_2 = uibutton(app.JogModePanel, 'push');
            app.ZButton_2.ButtonPushedFcn = createCallbackFcn(app, @ZButton_2Pushed, true);
            app.ZButton_2.Text = '+Z';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end